classdef nCORTEx_console < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        connectTarget                   matlab.ui.control.StateButton
        Switch_4                        matlab.ui.control.Switch
        PhotometryPanel                 matlab.ui.container.Panel
        FSEditField                     matlab.ui.control.NumericEditField
        FSEditFieldLabel                matlab.ui.control.Label
        Switch_3                        matlab.ui.control.Switch
        DoricConfigDropDown             matlab.ui.control.DropDown
        DoricConfigDropDownLabel        matlab.ui.control.Label
        LoggingLabel                    matlab.ui.control.Label
        PiezoPanel                      matlab.ui.container.Panel
        ModelDropDown_7                 matlab.ui.control.DropDown
        ModelDropDown_7Label            matlab.ui.control.Label
        SelectProjectDirectoryDropDown  matlab.ui.control.DropDown
        SelectProjectDirectoryDropDownLabel  matlab.ui.control.Label
        NeuropixelsPanel                matlab.ui.container.Panel
        FSEditField_2                   matlab.ui.control.NumericEditField
        FSEditField_2Label              matlab.ui.control.Label
        Switch_2                        matlab.ui.control.Switch
        SGLXConfigDropDown              matlab.ui.control.DropDown
        SGLXConfigDropDownLabel         matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        DataStreamingLabel              matlab.ui.control.Label
        PhaseDropDown                   matlab.ui.control.DropDown
        PhaseDropDownLabel              matlab.ui.control.Label
        SaveSessionDataButton           matlab.ui.control.Button
        VisionPanel                     matlab.ui.container.Panel
        ModelDropDown_9                 matlab.ui.control.DropDown
        ModelDropDown_9Label            matlab.ui.control.Label
        WhiskFSEditField                matlab.ui.control.NumericEditField
        WhiskFSEditFieldLabel           matlab.ui.control.Label
        PupilFSEditField                matlab.ui.control.NumericEditField
        PupilFSEditFieldLabel           matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        SwitchLabel                     matlab.ui.control.Label
        DLCConfigDropDown               matlab.ui.control.DropDown
        DLCConfigDropDownLabel          matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function BrowseButtonPushed(app, event)
            path=uigetdir;
            app.SelectSaveLocationLabel.Text=path;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            params = setSlParams(struct);
            tg = slrealtime;
            disp(strcat('Accessing', {' '},tg.TargetSettings.name,{'...'}));
            slbuild('nCORTEx');
            addpath('nCORTEx_slrealtime_rtw');
            addpath(genpath('nCORTEx_slrealtime_rtw'));
            load(tg,'nCORTEx.mldatx');
            start(tg);

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            tg = slrealtime;            
            % save("/home/electro/Code_Repo/n-CORTEx/log.mat",'logsout')
            stop(tg);
            disp(strcat('Application',{' '},'Stopped'))
        end

        % Button pushed function: SaveSessionDataButton
        function SaveSessionDataButtonPushed(app, event)
            savePath = app.SelectProjectDirectoryDropDownLabel.Text;
            disp(['saving log data to: ',savePath]);
            logData = evalin('base','logsout');
            save(fullfile(app.SelectProjectDirectoryDropDownLabel.Text,"log.mat"),'logData')
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            switch value
                case 'On'
                    app.VisionPanel.ForegroundColor=[1,1,1];
                case 'Off'
                    app.VisionPanel.ForegroundColor=[0.36,0.36,0.36];
            end
            
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            switch value
                case 'On'
                    app.NeuropixelsPanel.ForegroundColor=[1,1,1];
                case 'Off'
                    app.NeuropixelsPanel.ForegroundColor=[0.36,0.36,0.36];
            end
            
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            value = app.Switch_3.Value;
            switch value
                case 'On'
                    app.PhotometryPanel.ForegroundColor=[1,1,1];
                case 'Off'
                    app.PhotometryPanel.ForegroundColor=[0.36,0.36,0.36];
            end
            
        end

        % Value changed function: Switch_4
        function Switch_4ValueChanged(app, event)
            value = app.Switch_4.Value;
            switch value
                case 'On'
                    app.PiezoPanel.ForegroundColor=[1,1,1];
                case 'Off'
                    app.PiezoPanel.ForegroundColor=[0.36,0.36,0.36];
            end
            
        end

        % Callback function: not associated with a component
        function StopButton_2Pushed(app, event)
            tg = slrealtime;
            switch tg.isConnected
                case 1
                case 0
            end
        end

        % Value changed function: connectTarget
        function connectTargetValueChanged(app, event)
            value = app.connectTarget.Value;
            switch value
                case 1
                    tg = slrealtime;
                    disp('connecting...')
                    tg.connect;                    
                    disp(strcat(tg.TargetSettings.name,{' '},'connected'))
                    app.connectTarget.Icon='slrtConnectIcon.png';
                case 0
                    tg = slrealtime;
                    disp('disconnecting...')
                    tg.disconnect;                    
                    disp(strcat(tg.TargetSettings.name,{' '},'disconnected'))
                    app.connectTarget.Icon='slrtDisconnectIcon.png';
            end
        end

        % Value changed function: SelectProjectDirectoryDropDown
        function SelectProjectDirectoryDropDownValueChanged(app, event)
            value = app.SelectProjectDirectoryDropDown.Value;
            params = setSlParams(struct);
            switch value
                case 'Neuromodulation for Pain'
                    app.SelectProjectDirectoryDropDownLabel.Text = fullfile(params.paths.NECdrive_root,'Project_Neuromodulation-for-Pain','Raw Behavior Data');
                case 'Neurotransmitter Exploration'
                    app.SelectProjectDirectoryDropDownLabel.Text = fullfile(params.paths.NECdrive_root,'Project_Neurotransmitter-Exploration','Raw Behavior Data');
                case 'Thalamic-Pupil Synch'
                    app.SelectProjectDirectoryDropDownLabel.Text = fullfile(params.paths.NECdrive_root,'Project_Thalamic-Pupil-Synchronization','Raw Behavior Data');
                case 'SSD'
                    % app.SelectProjectDirectoryDropDownLabel.Text = fullfile(params.paths.NECdrive_root,'Project_Neurotransmitter-Exploration','Raw Behavior Data');;
                    app.SelectProjectDirectoryDropDownLabel.Text = 'SSD missing mountpoint';
                    disp('warning: SSD missing mountpoint')
                case 'Browse'
                    path = uigetdir;
                    app.SelectProjectDirectoryDropDownLabel.Text = path;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VisionPanel = uipanel(app.UIFigure);
            app.VisionPanel.Title = 'Vision';
            app.DLCConfigDropDownLabel = uilabel(app.VisionPanel);
            app.DLCConfigDropDownLabel.Text = 'DLC Config';
            app.DLCConfigDropDown = uidropdown(app.VisionPanel);
            app.DLCConfigDropDown.Value = 'Option 3';
            app.SwitchLabel = uilabel(app.VisionPanel);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.VisionPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'On';
            app.PupilFSEditFieldLabel = uilabel(app.VisionPanel);
            app.PupilFSEditFieldLabel.Text = 'Pupil FS';
            app.PupilFSEditField = uieditfield(app.VisionPanel, 'numeric');
            app.WhiskFSEditFieldLabel = uilabel(app.VisionPanel);
            app.WhiskFSEditFieldLabel.Text = 'Whisk FS';
            app.WhiskFSEditField = uieditfield(app.VisionPanel, 'numeric');
            app.ModelDropDown_9Label = uilabel(app.VisionPanel);
            app.ModelDropDown_9Label.Text = 'Model';
            app.ModelDropDown_9 = uidropdown(app.VisionPanel);
            app.ModelDropDown_9.Value = 'Option 3';
            app.SaveSessionDataButton = uibutton(app.UIFigure, 'push');
            app.SaveSessionDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSessionDataButtonPushed, true);
            app.SaveSessionDataButton.Text = 'Save Session Data';
            app.PhaseDropDownLabel = uilabel(app.UIFigure);
            app.PhaseDropDownLabel.Text = 'Phase';
            app.PhaseDropDown = uidropdown(app.UIFigure);
            app.DataStreamingLabel = uilabel(app.UIFigure);
            app.DataStreamingLabel.Text = 'Data Streaming';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = 'slrtRunIcon.png';
            app.StartButton.Text = '';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = 'slrtStopIcon.png';
            app.StopButton.Text = '';
            app.NeuropixelsPanel = uipanel(app.UIFigure);
            app.NeuropixelsPanel.Title = 'Neuropixels';
            app.SGLXConfigDropDownLabel = uilabel(app.NeuropixelsPanel);
            app.SGLXConfigDropDownLabel.Text = 'SGLX Config';
            app.SGLXConfigDropDown = uidropdown(app.NeuropixelsPanel);
            app.SGLXConfigDropDown.Value = 'Option 3';
            app.Switch_2 = uiswitch(app.NeuropixelsPanel, 'slider');
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'On';
            app.FSEditField_2Label = uilabel(app.NeuropixelsPanel);
            app.FSEditField_2Label.Text = 'FS';
            app.FSEditField_2 = uieditfield(app.NeuropixelsPanel, 'numeric');
            app.SelectProjectDirectoryDropDownLabel = uilabel(app.UIFigure);
            app.SelectProjectDirectoryDropDownLabel.Text = 'Select Project Directory';
            app.SelectProjectDirectoryDropDown = uidropdown(app.UIFigure);
            app.SelectProjectDirectoryDropDown.Items = {'Neuromodulation for Pain', 'Neurotransmitter Exploration', 'Thalamic-Pupil Synch', 'SSD', 'Browse'};
            app.SelectProjectDirectoryDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectProjectDirectoryDropDownValueChanged, true);
            app.SelectProjectDirectoryDropDown.Value = 'Browse';
            app.PiezoPanel = uipanel(app.UIFigure);
            app.PiezoPanel.Title = 'Piezo';
            app.ModelDropDown_7Label = uilabel(app.PiezoPanel);
            app.ModelDropDown_7Label.Text = 'Model';
            app.ModelDropDown_7 = uidropdown(app.PiezoPanel);
            app.ModelDropDown_7.Value = 'Option 3';
            app.LoggingLabel = uilabel(app.UIFigure);
            app.LoggingLabel.Text = 'Logging';
            app.PhotometryPanel = uipanel(app.UIFigure);
            app.PhotometryPanel.Title = 'Photometry';
            app.DoricConfigDropDownLabel = uilabel(app.PhotometryPanel);
            app.DoricConfigDropDownLabel.Text = 'Doric Config';
            app.DoricConfigDropDown = uidropdown(app.PhotometryPanel);
            app.DoricConfigDropDown.Value = 'Option 3';
            app.Switch_3 = uiswitch(app.PhotometryPanel, 'slider');
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Value = 'On';
            app.FSEditFieldLabel = uilabel(app.PhotometryPanel);
            app.FSEditFieldLabel.Text = 'FS';
            app.FSEditField = uieditfield(app.PhotometryPanel, 'numeric');
            app.Switch_4 = uiswitch(app.UIFigure, 'slider');
            app.Switch_4.ValueChangedFcn = createCallbackFcn(app, @Switch_4ValueChanged, true);
            app.Switch_4.Value = 'On';
            app.connectTarget = uibutton(app.UIFigure, 'state');
            app.connectTarget.ValueChangedFcn = createCallbackFcn(app, @connectTargetValueChanged, true);
            app.connectTarget.Icon = 'slrtDisconnectIcon.png';
            app.connectTarget.Text = 'Button';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nCORTEx_console

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end